classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GuiUIFigure              matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        MainTab                  matlab.ui.container.Tab
        LoadleftImageButton      matlab.ui.control.Button
        LoadrightImageButton     matlab.ui.control.Button
        runcomputeButton         matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        DisplacementSlider       matlab.ui.control.Slider
        Displacement_valueLabel  matlab.ui.control.Label
        UIAxes2_2                matlab.ui.control.UIAxes
        saveButton               matlab.ui.control.Button
        fullsizeButton           matlab.ui.control.Button
        TextArea                 matlab.ui.control.TextArea
        RecitifyTab              matlab.ui.container.Tab
        DisperityMapTab          matlab.ui.container.Tab
        Button4                  matlab.ui.control.Button
        ScalingSlider            matlab.ui.control.Slider
        ScalingLabel             matlab.ui.control.Label
        ReconstructionTab        matlab.ui.container.Tab
        TimeTab                  matlab.ui.container.Tab
    end

    
    properties (Access = public)
        %Property % Description
        figure_image_l;
        Image_L;
        figure_image_r;
        Image_R;
        figure_image_output;
        image_output;
        output_image;
        
        %% Parameter
        Scaling = 1;
        p = 0.5;
        halfBolcksize = 0;
        disparityRange = 400;


        %% state String
        str_state;
        
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.figure_image_l = app.UIAxes;
            app.figure_image_r = app.UIAxes2;
            app.figure_image_output = app.UIAxes2_2;
            
            %% READ IMAGE Left
            app.Image_L = imread('img/L2.JPG');
            I = imshow(app.Image_L ,'parent', app.figure_image_l);
            app.figure_image_l.XLim = [0 I.XData(2)];
            app.figure_image_l.YLim = [0 I.YData(2)];
            %set(app.UIAxes.XAxis, 'visible', 'off');
            %set(app.UIAxes.YAxis, 'visible', 'off');
            %set(app.UIAxes.YAxis, 'color', 'none');
            
            app.Image_R = imread('img/R2.JPG');
            I = imshow(app.Image_R ,'parent', app.figure_image_r);
            app.figure_image_r.XLim = [0 I.XData(2)];
            app.figure_image_r.YLim = [0 I.YData(2)];

            
            %% LOAD last output image
            char_p = num2str(app.p,'%01.1f\n');
            no_pic = false;
            try
                app.image_output = imread(strcat('results/p=',char_p,'.jpg'));
            catch ME
                no_pic = true;     
            end
            if no_pic 

            else
                I = imshow(app.image_output ,'parent',app.figure_image_output);
                app.figure_image_output.XLim = [0 I.XData(2)];
                app.figure_image_output.YLim = [0 I.YData(2)];
            end
            %% Load default values
            app.str_state = app.TextArea;
            app.str_state.Value = {'Idle', '-----------'};


            %% SET Parameter
            app.Scaling = 0.1;
            app.p = 0.5;
            app.halfBolcksize = 2;
            app.disparityRange = 400;
            

        end

        % Button pushed function: LoadleftImageButton
        function LoadleftImageButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg','*.png'}, 'Select an image');
            app.Image_L = imread(fullfile(path,file));
            I = imshow(app.Image_L ,'parent',app.figure_image_l);
            app.figure_image_l.XLim = [0 I.XData(2)];
            app.figure_image_l.YLim = [0 I.YData(2)];
        end

        % Button pushed function: LoadrightImageButton
        function LoadrightImageButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg','*.png'}, 'Select an image');
            app.Image_R = imread(fullfile(path,file));
            I = imshow(app.Image_R ,'parent',app.figure_image_r);
            app.figure_image_r.XLim = [0 I.XData(2)];
            app.figure_image_r.YLim = [0 I.YData(2)];
        end

        % Value changed function: DisplacementSlider
        function DisplacementSliderValueChanged(app, event)
            app.p = app.DisplacementSlider.Value;
            %% LOAD last output image
            char_p = num2str(app.p,'%01.1f\n');
            no_pic = false;
            try
                app.image_output = imread(strcat('results/p=',char_p,'.jpg'));
            catch ME
                no_pic = true;     
            end
            if no_pic 
                % schow bad template pic
            else
                I = imshow(app.image_output ,'parent',app.figure_image_output);
                app.figure_image_output.XLim = [0 I.XData(2)];
                app.figure_image_output.YLim = [0 I.YData(2)];
            end
            
        end

        % Button pushed function: runcomputeButton
        function runcomputeButtonPushed(app, event)
           %% Execute free_viewpoint function
            harris_var =         {'segment_length',15 ...
                                 ,'k',0.04 ...
                                 ,'min_dist',50 ...
                                 ,'N',5 ...
                                 ,'tau',10^6 ...
                                 ,'do_plot',false ...
                                 };
            correspondence_var = {'window_length',25 ...
                                 ,'min_corr',0.91 ...
                                 ,'do_plot',false
                                 };
    
            ramsac_var =         {'tolerance', 0.001};
    
            rectifiy_var =       {'harris_var', harris_var ...
                                 ,'correspondence_var', correspondence_var...
                                 ,'ramsac_var', ramsac_var...
                                 };
  
           
           % pack parameter
             disparity_var = {'disparityRange', [-400 400] ...
                                ,'halfBolcksize', 0 ...
                                ,'dispMap_typ', 'colorBlocks' ...
                                ,'scaling', app.Scaling ...
                                };
             all_var =          {'do_print', false ...
                                ,'displacement', app.p ...
                                ,'disparity_var', disparity_var ...
                                ,'rectifiy_var', rectifiy_var...
                                ,'gui_text_box', app.str_state ...
                                };
           
            try
                app.str_state.Value = {'computing state follows'};
                app.image_output = free_viewpoint(app.Image_R,  app.Image_L, ...
                                                all_var{:});
            catch inputError
                disp(strcat('ERROR: ', inputError.message))
                return;
            end
            
            I = imshow(app.image_output ,'parent',app.figure_image_output);
            app.figure_image_output.XLim = [0 I.XData(2)];
            app.figure_image_output.YLim = [0 I.YData(2)];
            app.str_state.Value = {'Idle'};

        end

        % Value changed function: ScalingSlider
        function ScalingSliderValueChanged(app, event)
            app.Scaling = app.ScalingSlider.Value;

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GuiUIFigure = uifigure;
            app.GuiUIFigure.Name = 'Gui';
            app.TabGroup = uitabgroup(app.GuiUIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.LoadleftImageButton = uibutton(app.MainTab, 'push');
            app.LoadleftImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadleftImageButtonPushed, true);
            app.LoadleftImageButton.Text = 'Load left Image';
            app.LoadrightImageButton = uibutton(app.MainTab, 'push');
            app.LoadrightImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadrightImageButtonPushed, true);
            app.LoadrightImageButton.Text = 'Load right Image';
            app.runcomputeButton = uibutton(app.MainTab, 'push');
            app.runcomputeButton.ButtonPushedFcn = createCallbackFcn(app, @runcomputeButtonPushed, true);
            app.runcomputeButton.Text = 'run compute';
            app.UIAxes = uiaxes(app.MainTab);
            app.UIAxes2 = uiaxes(app.MainTab);
            app.DisplacementSlider = uislider(app.MainTab);
            app.DisplacementSlider.Limits = [0 1];
            app.DisplacementSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.DisplacementSlider.ValueChangedFcn = createCallbackFcn(app, @DisplacementSliderValueChanged, true);
            app.DisplacementSlider.MinorTicks = [];
            app.DisplacementSlider.Value = 0.5;
            app.Displacement_valueLabel = uilabel(app.MainTab);
            app.Displacement_valueLabel.Text = 'Displacement_value';
            app.UIAxes2_2 = uiaxes(app.MainTab);
            app.saveButton = uibutton(app.MainTab, 'push');
            app.saveButton.Text = 'save';
            app.fullsizeButton = uibutton(app.MainTab, 'push');
            app.fullsizeButton.Text = 'fullsize';
            app.TextArea = uitextarea(app.MainTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Enable = 'off';
            app.TextArea.Value = {'idle'; '-----'; ''};
            app.RecitifyTab = uitab(app.TabGroup);
            app.RecitifyTab.Title = 'Recitify';
            app.DisperityMapTab = uitab(app.TabGroup);
            app.DisperityMapTab.Title = 'DisperityMap';
            app.Button4 = uibutton(app.DisperityMapTab, 'push');
            app.Button4.Text = 'Button4';
            app.ScalingSlider = uislider(app.DisperityMapTab);
            app.ScalingSlider.Limits = [0.1 1];
            app.ScalingSlider.MajorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ScalingSlider.MajorTickLabels = {'0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.ScalingSlider.ValueChangedFcn = createCallbackFcn(app, @ScalingSliderValueChanged, true);
            app.ScalingSlider.Value = 1;
            app.ScalingLabel = uilabel(app.DisperityMapTab);
            app.ScalingLabel.Text = 'Scaling';
            app.ReconstructionTab = uitab(app.TabGroup);
            app.ReconstructionTab.Title = 'Reconstruction';
            app.TimeTab = uitab(app.TabGroup);
            app.TimeTab.Title = 'Time';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GuiUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GuiUIFigure)
        end
    end
end